classdef guiHighBall < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup2                matlab.ui.container.TabGroup
        SendMessageTab           matlab.ui.container.Tab
        XComponentLabel          matlab.ui.control.Label
        AltitudeSetpointLabel    matlab.ui.control.Label
        Altitude_Setpoint        matlab.ui.control.NumericEditField
        AltitudeRangeLabel       matlab.ui.control.Label
        Altitude_Range           matlab.ui.control.NumericEditField
        AltitudeLimitsLabel      matlab.ui.control.Label
        TemperatureControlLabel  matlab.ui.control.Label
        ValueLabel               matlab.ui.control.Label
        Temp                     matlab.ui.control.NumericEditField
        SetModeButtonGroup       matlab.ui.container.ButtonGroup
        ManualButton             matlab.ui.control.ToggleButton
        AdvancedButton           matlab.ui.control.ToggleButton
        TabGroup3                matlab.ui.container.TabGroup
        ManualTab                matlab.ui.container.Tab
        AdjustmentValuesLabel    matlab.ui.control.Label
        HeliumVentsLabel         matlab.ui.control.Label
        Helium_Vent              matlab.ui.control.NumericEditField
        BallastDropsLabel        matlab.ui.control.Label
        Ballast_Drop             matlab.ui.control.NumericEditField
        CutdownButton            matlab.ui.control.StateButton
        ManualAdjustmentButton   matlab.ui.control.StateButton
        AdvancedTab              matlab.ui.container.Tab
        HeliumWeightsLabel       matlab.ui.control.Label
        AlphaLabel               matlab.ui.control.Label
        Helium_Alpha             matlab.ui.control.NumericEditField
        BetaLabel                matlab.ui.control.Label
        Helium_Beta              matlab.ui.control.NumericEditField
        GammaLabel               matlab.ui.control.Label
        Helium_Gamma             matlab.ui.control.NumericEditField
        BallastWeightsLabel      matlab.ui.control.Label
        BetaLabel_2              matlab.ui.control.Label
        Ballast_Beta             matlab.ui.control.NumericEditField
        GammaLabel_2             matlab.ui.control.Label
        Ballast_Gamma            matlab.ui.control.NumericEditField
        AlphaLabel_2             matlab.ui.control.Label
        Ballast_Alpha            matlab.ui.control.NumericEditField
        MiscLabel                matlab.ui.control.Label
        MaxVelocityLabel         matlab.ui.control.Label
        MaxVelocity              matlab.ui.control.NumericEditField
        SendMessageButton        matlab.ui.control.StateButton
        LatitudeLabel_2          matlab.ui.control.Label
        Latitude                 matlab.ui.control.NumericEditField
        PositionLabel_2          matlab.ui.control.Label
        LongitudeLabel_2         matlab.ui.control.Label
        Longitude                matlab.ui.control.NumericEditField
        SecondsLabel             matlab.ui.control.Label
        Update_Rate              matlab.ui.control.NumericEditField
        UpdateRateLabel          matlab.ui.control.Label
        SentMessageListTab       matlab.ui.container.Tab
        UITable                  matlab.ui.control.Table
        ExportDataButton         matlab.ui.control.Button
        ViewRecievedMessagesTab  matlab.ui.container.Tab
        LatitudeLabel            matlab.ui.control.Label
        Latitude_rec             matlab.ui.control.NumericEditField
        LongitudeLabel           matlab.ui.control.Label
        Longitude_rec            matlab.ui.control.NumericEditField
        AltitudeLabel            matlab.ui.control.Label
        Altitude_rec             matlab.ui.control.NumericEditField
        PositionLabel            matlab.ui.control.Label
        ToViewDataSelectTimeReceivedDropDownLabel  matlab.ui.control.Label
        TimeReceivedDropDown     matlab.ui.control.DropDown
        MagnitudeLabel           matlab.ui.control.Label
        Velocity                 matlab.ui.control.NumericEditField
        VelocityLabel            matlab.ui.control.Label
        AccelerationLabel        matlab.ui.control.Label
        VerticalMagLabel         matlab.ui.control.Label
        Acceleration_V           matlab.ui.control.NumericEditField
        HorizontalMagLabel       matlab.ui.control.Label
        Acceleration_H           matlab.ui.control.NumericEditField
        DirectionLabel           matlab.ui.control.Label
        Acceleration_Th          matlab.ui.control.NumericEditField
        TemperatureLabel         matlab.ui.control.Label
        Temperature_rec          matlab.ui.control.NumericEditField
        PressureLabel            matlab.ui.control.Label
        Pressure                 matlab.ui.control.NumericEditField
        AmbientConditionsLabel   matlab.ui.control.Label
        PayloadStatusLabel       matlab.ui.control.Label
        HeliumVentedLabel        matlab.ui.control.Label
        Helium_rec               matlab.ui.control.NumericEditField
        PayloadStatusLabel_2     matlab.ui.control.Label
        Ballast_rec              matlab.ui.control.NumericEditField
        BallastDroppedLabel      matlab.ui.control.Label
        StateEditFieldLabel      matlab.ui.control.Label
        StateEditField           matlab.ui.control.EditField
        EmergencyLampLabel       matlab.ui.control.Label
        EmergencyLamp            matlab.ui.control.Lamp
        ControlEditFieldLabel    matlab.ui.control.Label
        ControlEditField         matlab.ui.control.EditField
        RecievedDataGraphsTab    matlab.ui.container.Tab
        ExportDataButton_2       matlab.ui.control.Button
        DataTypeButtonGroup      matlab.ui.container.ButtonGroup
        AltitudeButton           matlab.ui.control.RadioButton
        LatitudeButton           matlab.ui.control.RadioButton
        TemperatureButton        matlab.ui.control.RadioButton
        PressureButton           matlab.ui.control.RadioButton
        BallastDroppedButton     matlab.ui.control.RadioButton
        VertAccButton            matlab.ui.control.RadioButton
        VertVelocityButton       matlab.ui.control.RadioButton
        HeliumVentedButton       matlab.ui.control.RadioButton
        LongitudeButton          matlab.ui.control.RadioButton
        UIAxes                   matlab.ui.control.UIAxes
        AcknowledgeButton        matlab.ui.control.Button
        NoNewMessagesLampLabel   matlab.ui.control.Label
        NoNewMessagesLamp        matlab.ui.control.Lamp
        CheckMessagesButton      matlab.ui.control.Button
    end


properties (Access = private)
        RecD % Queue of received messages with time stamps
        RecT % cell of time strings
        OutM % message to payload
        OutD % data to encode in message
        t
        c_time
    end
    
    methods (Access = private)
        
        function [Encoded] = decode_message(app, Data)
%             Encoded = false(1,240);
%             for val = 1:30
%                 a = (val-1)*8 + 1;
%                 Encoded(a:a+7) = int2bin(app, Data(val), 8);
%             end

            Encoded = Data(1:end); 
            
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'dd-mm-yy_HH-MM-ss');
            
            app.RecD.Date = [app.RecD.Date, {date}];
            if app.TimeReceivedDropDown.ItemsData == 0
                app.TimeReceivedDropDown.Items = {date};
                app.TimeReceivedDropDown.ItemsData = 1;
            else
            app.TimeReceivedDropDown.Items = [app.TimeReceivedDropDown.Items, {date}];
            app.TimeReceivedDropDown.ItemsData = [app.TimeReceivedDropDown.ItemsData, app.TimeReceivedDropDown.ItemsData(end) + 1];
            end
                        
            % Temperature Data
            TEMP_MIN = -88;
            TEMP_STEP = .25;
            TEMP_OFFSET = 0;
            TEMP_BITS = 9;
            app.RecD.Temp = [app.RecD.Temp, decode(app, TEMP_MIN, TEMP_STEP, Encoded(TEMP_OFFSET+1:TEMP_OFFSET+TEMP_BITS))];
            
            % Pressure Data
            PRS_MIN = 0;
            PRS_STEP = .25;
            PRS_OFFSET = 9;
            PRS_BITS = 12;
            app.RecD.Prs = [app.RecD.Prs, decode(app, PRS_MIN, PRS_STEP, Encoded(PRS_OFFSET+1:PRS_OFFSET+PRS_BITS))];
            
            % IMU Data
            ACC_VER_MIN = 0;
            ACC_VER_STEP = .001;
            ACC_VER_OFFSET = 21;
            ACC_VER_BITS = 14;
            app.RecD.AccV = [app.RecD.AccV, decode(app, ACC_VER_MIN, ACC_VER_STEP, Encoded(ACC_VER_OFFSET+1:ACC_VER_OFFSET+ACC_VER_BITS))];
            
            ACC_HOR_MIN = 0;
            ACC_HOR_STEP = 0.001;
            ACC_HOR_OFFSET = 35;
            ACC_HOR_BITS = 14;
            app.RecD.AccH = [app.RecD.AccH, decode(app, ACC_HOR_MIN, ACC_HOR_STEP, Encoded(ACC_HOR_OFFSET+1:ACC_HOR_OFFSET+ACC_HOR_BITS))];
            
            ACC_THETA_MIN = 0;
            ACC_THETA_STEP = 0.00628318531;
            ACC_THETA_OFFSET = 49;
            ACC_THETA_BITS = 10;
            app.RecD.AccTheta = [app.RecD.AccTheta, decode(app, ACC_THETA_MIN, ACC_THETA_STEP, Encoded(ACC_THETA_OFFSET+1:ACC_THETA_OFFSET+ACC_THETA_BITS))];
            
            % GPS Data
            ALT_MIN = 0;
            ALT_STEP = 9;
            ALT_OFFSET = 59;
            ALT_BITS = 14;
            app.RecD.Alt = [app.RecD.Alt, decode(app, ALT_MIN, ALT_STEP, Encoded(ALT_OFFSET+1:ALT_OFFSET+ALT_BITS))];
            
            LAT_MIN = 18;
            LAT_STEP = .000005;
            LAT_OFFSET = 73;
            LAT_BITS = 23;
            app.RecD.Lat = [app.RecD.Lat, decode(app, LAT_MIN, LAT_STEP, Encoded(LAT_OFFSET+1:LAT_OFFSET+LAT_BITS))];
            
            LONG_MIN = -100;
            LONG_STEP = .000005;
            LONG_OFFSET = 96;
            LONG_BITS = 23;
            app.RecD.Long = [app.RecD.Long, decode(app, LONG_MIN, LONG_STEP, Encoded(LONG_OFFSET+1:LONG_OFFSET+LONG_BITS))];
            
            % Velovity Data
            VEL_MAG_MIN = 0;
            VEL_MAG_STEP = 0.0005;
            VEL_MAG_OFFSET = 119;
            VEL_MAG_BITS = 16;
            app.RecD.Vel = [app.RecD.Vel, decode(app, VEL_MAG_MIN, VEL_MAG_STEP, Encoded(VEL_MAG_OFFSET+1:VEL_MAG_OFFSET+VEL_MAG_BITS))];
            
            % Helium Data
            HEL_MIN = 0;
            HEL_STEP = .1;
            HEL_OFFSET = 135;
            HEL_BITS = 10;
            app.RecD.Hel = [app.RecD.Hel, decode(app, HEL_MIN, HEL_STEP, Encoded(HEL_OFFSET+1:HEL_OFFSET+HEL_BITS))];
            
            % Ballast Data
            BALL_MIN = 0;
            BALL_STEP = .1;
            BALL_OFFSET = 145;
            BALL_BITS = 10;
            app.RecD.Bal = [app.RecD.Bal, decode(app, BALL_MIN, BALL_STEP, Encoded(BALL_OFFSET+1:BALL_OFFSET+BALL_BITS))];
            
            % Time Data
%             TIME_MIN = 0;
%             TIME_STEP = 1;
%             TIME_OFFSET = 155;
%             TIME_BITS = 17;
            app.RecT = [app.RecT, (now - app.c_time)];
            
            % State Data
            STATE_OFFSET = 172;
            STATE_BITS = 3;
            app.RecD.State = [app.RecD.State, decode(app, 0, 1, Encoded(STATE_OFFSET+1:STATE_OFFSET+STATE_BITS))];
            
            % Control Mode Data
            CRTL_OFFSET = 175;
            CRTL_BITS = 2;
            app.RecD.Ctrl = [app.RecD.Ctrl, decode(app, 0, 1, Encoded(CRTL_OFFSET+1:CRTL_OFFSET+CRTL_BITS))];
            
            % Emergency Data
            EMRG_OFFSET = 177;
            EMRG_BITS = 2;
            app.RecD.Emrg = [app.RecD.Emrg, decode(app, 0, 1, Encoded(EMRG_OFFSET+1:EMRG_OFFSET+EMRG_BITS))];
   
        end
        
        function value = decode(app, min, step, bin)
            input = log2dec(app, bin);
            value = (input*step)+min;
        end
    
        function [dec] = log2dec(app, bin)
            long = length(bin);
            swap = long:-1:1;
            dec = 0;
            for i = 1:long
                if bin(swap(i))
                    dec = dec + 2^(i-1);
                end
            end
        end
    
        function [bin] = int2bin(app, integer, long)
            integer = round(integer);
            if integer == 0
                bin = zeros(1,long);
            elseif integer < 0
                bin = zeros(1,long);
                f = uifigure;
                message = sprintf('Value below expected minimum! \n Consider changing input.');
                uialert(f,message,'Warning',...
                    'Icon','warning');
            else
                l = floor(log2(integer));
                
                integer = integer - 2^l;
                bin = true(1,l+1);
                for val = l-1:-1:0
                    if (integer < 2^val)
                        bin(l+1-val) = false;
                    else
                        integer = integer - 2^val;
                    end
                end
                if length(bin) ~= long
                    start = false(1,(long-l-1));
                    bin = [start, bin];
                end
            end
        end
        
        function [message] = encode_message(app)
            message = false(1,240);
            
            %altitude
            ALT_MIN = 0;
            ALT_STEP = 15;
            ALT_OFFSET = 0;
            ALT_LENGTH = 13;
            ALT_VALUE = (app.OutD.Alt(end) - ALT_MIN)/ALT_STEP;
            message(1+ALT_OFFSET:ALT_OFFSET+ALT_LENGTH) = int2bin(app, ALT_VALUE, ALT_LENGTH);
            
            % altitude_buffer
            ALT_BUF_MIN = 0;
            ALT_BUF_STEP = 15;
            ALT_BUF_OFFSET = 13;
            ALT_BUF_LENGTH = 11;
            ALT_BUF_VALUE = (app.OutD.AltBuf(end)- ALT_BUF_MIN)/ALT_BUF_STEP;
            message(1+ALT_BUF_OFFSET:ALT_BUF_OFFSET+ALT_BUF_LENGTH) = int2bin(app, ALT_BUF_VALUE, ALT_BUF_LENGTH);
            
            % lat_degree
            LAT_MIN = 18;
            LAT_STEP = .000005;
            LAT_OFFSET = 24;
            LAT_LENGTH = 23;
            LAT_VALUE = (app.OutD.Lat(end)- LAT_MIN)/LAT_STEP;
            message(1+LAT_OFFSET:LAT_OFFSET+LAT_LENGTH) = int2bin(app, LAT_VALUE, LAT_LENGTH);
            
            % long_degree
            LONG_MIN = -100;
            LONG_STEP = .000005;
            LONG_OFFSET = 47;
            LONG_LENGTH = 23;
            LONG_VALUE = (app.OutD.Long(end) - LONG_MIN)/LONG_STEP;
            message(1+LONG_OFFSET:LONG_OFFSET+LONG_LENGTH) = int2bin(app, LONG_VALUE, LONG_LENGTH);

            % cutdown
            CUT_MIN = 0;
            CUT_STEP = 1;
            CUT_OFFSET = 70;
            CUT_LENGTH = 2;
            CUT_VALUE = (app.OutD.Cut(end) - CUT_MIN)/CUT_STEP;
            message(1+CUT_OFFSET:CUT_OFFSET+CUT_LENGTH) = int2bin(app, CUT_VALUE, CUT_LENGTH);
            
            % update_rate
            UPD_MIN = 0;
            UPD_STEP = 1;
            UPD_OFFSET = 72;
            UPD_LENGTH = 10;
            UPD_VALUE = (app.OutD.Upd(end) - UPD_MIN)/UPD_STEP;
            message(1+UPD_OFFSET:UPD_OFFSET+UPD_LENGTH) = int2bin(app, UPD_VALUE, UPD_LENGTH);
            
            % hel_alpha
            HEL_A_MIN = 0;
            HEL_A_STEP = .001;
            HEL_A_OFFSET = 82;
            HEL_A_LENGTH = 10;
            HEL_A_VALUE = (app.OutD.HelA(end) - HEL_A_MIN)/HEL_A_STEP;
            message(1+HEL_A_OFFSET:HEL_A_OFFSET+HEL_A_LENGTH) = int2bin(app, HEL_A_VALUE, HEL_A_LENGTH);
            
            % hel_beta
            HEL_B_MIN = 0;
            HEL_B_STEP = .1;
            HEL_B_OFFSET = 92;
            HEL_B_LENGTH = 10;
            HEL_B_VALUE = (app.OutD.HelB(end) - HEL_B_MIN)/HEL_B_STEP;
            message(1+HEL_B_OFFSET:HEL_B_OFFSET+HEL_B_LENGTH) = int2bin(app, HEL_B_VALUE, HEL_B_LENGTH);
            
            % hel_gamma
            HEL_G_MIN = 0;
            HEL_G_STEP = .01;
            HEL_G_OFFSET = 102;
            HEL_G_LENGTH = 10;
            HEL_G_VALUE = (app.OutD.HelG(end) - HEL_G_MIN)/HEL_G_STEP;
            message(1+HEL_G_OFFSET:HEL_G_OFFSET+HEL_G_LENGTH) = int2bin(app, HEL_G_VALUE, HEL_G_LENGTH);
            
            % bal_alpha
            BAL_A_MIN = 0;
            BAL_A_STEP = .001;
            BAL_A_OFFSET = 112;
            BAL_A_LENGTH = 10;
            BAL_A_VALUE = (app.OutD.BalA(end) - BAL_A_MIN)/BAL_A_STEP;
            message(1+BAL_A_OFFSET:BAL_A_OFFSET+BAL_A_LENGTH) = int2bin(app, BAL_A_VALUE, BAL_A_LENGTH);
            
            % bal_beta
            BAL_B_MIN = 0;
            BAL_B_STEP = .1;
            BAL_B_OFFSET = 122;
            BAL_B_LENGTH = 10;
            BAL_B_VALUE = (app.OutD.BalB(end) - BAL_B_MIN)/BAL_B_STEP;
            message(1+BAL_B_OFFSET:BAL_B_OFFSET+BAL_B_LENGTH) = int2bin(app, BAL_B_VALUE, BAL_B_LENGTH);
            
            % bal_gamma
            BAL_G_MIN = 0;
            BAL_G_STEP = .01;
            BAL_G_OFFSET = 132;
            BAL_G_LENGTH = 10;
            BAL_G_VALUE = (app.OutD.BalG(end) - BAL_G_MIN)/BAL_G_STEP;
            message(1+BAL_G_OFFSET:BAL_G_OFFSET+BAL_G_LENGTH) = int2bin(app, BAL_G_VALUE, BAL_G_LENGTH);

            % temp_setpoint
            TEMP_MIN = 0;
            TEMP_STEP = 1;
            TEMP_OFFSET = 142;
            TEMP_LENGTH = 8;
            TEMP_VALUE = (app.OutD.Temp(end) - TEMP_MIN)/TEMP_STEP;
            message(1+TEMP_OFFSET:TEMP_OFFSET+TEMP_LENGTH) = int2bin(app, TEMP_VALUE, TEMP_LENGTH);
            
            % control_mode
            CTRL_MIN = 0;
            CTRL_STEP = 1;
            CTRL_OFFSET = 150;
            CTRL_LENGTH = 2;
            CTRL_VALUE = (app.OutD.Ctrl(end) - CTRL_MIN)/CTRL_STEP;
            message(1+CTRL_OFFSET:CTRL_OFFSET+CTRL_LENGTH) = int2bin(app, CTRL_VALUE, CTRL_LENGTH);
            
            % manual_adjust
            MAN_ADJ_OFFSET = 152;
            message(1+MAN_ADJ_OFFSET) = app.OutD.ManAdj(end);
            
            % manual_select
            MAN_SEL_OFFSET = 153;
            message(1+MAN_SEL_OFFSET) = app.OutD.ManSel(end);
            
            % manual_amount
            MAN_AMT_MIN = 0;
            MAN_AMT_STEP = 1;
            MAN_AMT_OFFSET = 154;
            MAN_AMT_LENGTH = 10;
            MAN_AMT_VALUE = (app.OutD.ManAmt(end) - MAN_AMT_MIN)/MAN_AMT_STEP;
            message(1+MAN_AMT_OFFSET:MAN_AMT_OFFSET+MAN_AMT_LENGTH) = int2bin(app, MAN_AMT_VALUE, MAN_AMT_LENGTH);
            
            % max velocity
            VEL_MIN = -40;
            VEL_STEP = .01;
            VEL_OFFSET = 164;
            VEL_LENGTH = 13;
            VEL_VALUE = (app.OutD.maxVel(end) - VEL_MIN)/VEL_STEP;
            message(1+VEL_OFFSET:VEL_OFFSET+VEL_LENGTH) = int2bin(app, VEL_VALUE, VEL_LENGTH);
            
        end
        
        function checkMessages(app)
            
            % Read Unread Emails in the folder
            mails = ReadOutlook(...
                'Folder', 'Senior Design SBD',...
                'Savepath', 'C:\Users\Kyle\Documents\GitHub\HighBall_Senior_Design\Software\Ground Communication\Incoming',...
                'Read',1 , ...
                'Mark',1);
               
            
            % Read data from incoming file and move it to another folder
            cd Incoming
            list = dir( '*.bin');
            cd ..
            num = length(list);
            
            if num > 0
                if num == 1
                    app.NoNewMessagesLampLabel.Text = '1 New Message';
                else
                    app.NoNewMessagesLampLabel.Text = [num2str(num), ' New Messages'];
                end
                app.NoNewMessagesLamp.Color = 'green';
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            else
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
                disp(['Run CheckMessage @ ', date])
            end
            
            while(num > 0)
                name = ['Incoming/',list(num).name];
                FID = fopen(name, 'r+');
                data = fread(FID, 'ubit1');
                for val = 1:24
                    a = (val-1)*8 + 1;
                    data(a:a+7) = data(a+7:-1:a);
                end
                fclose(FID);
                decode_message(app, data);
                movefile(name, 'Read', 'f');
                num = num - 1;
            end
        end
        
        function SendMessage(app)
            bin = app.OutM;
            
            % Write data to binary .sbd filed
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            file_name = [date,'data.sbd'];
            FID = fopen(file_name, 'w+');
            fwrite(FID, bin, 'ubit1');
            fclose('all');
            
            % Send email with sbd Attachment
            
%             % Define these variables appropriately:
%             mail = 'teamHighBall@yahoo.com'; %Your Yahoo email address
%             password = 'MITLLHighBall2018';  %Your Yahoo password
%             setpref('Internet','SMTP_Server','smtp.mail.yahoo.com');
%             setpref('Internet','E_mail',mail);
%             setpref('Internet','SMTP_Username',mail);
%             setpref('Internet','SMTP_Password',password);
%             props = java.lang.System.getProperties;
%             props.setProperty('mail.smtp.auth','true');
%             props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
%             props.setProperty('mail.smtp.socketFactory.port','465');
%             
%             to_mail =  'data@sbd.iridium.com'; % use this email for messages
%             % to_mail = 'kwatters6@gatech.edu'; % change to my email for testing
%             ISU = '300434062690230'; % ISU number here
%             % ISU = 'TestNumber';
            
%             sendmail(to_mail, ISU, 'Message is Unrelated', file_name)
            imei = '300434062690230';
            username = 'BrandonR@gatech.edu';
            password = 'jusT4saT18.';
            data =  bin2hex(app, bin);
            data(end-3:end) = 'AAAA';
            url = ['https://rockblock.rock7.com/rockblock/MT?imei=', imei, '&username=', username, '&password=', password, '&data=', data];
            web(url);
            
            % Move sent data to another folder and record data in queue
            movefile([date,'data.sbd'], 'Sent');
            S = fieldnames(app.OutD);
            r = length(S);
            c = length(app.OutD.(S{1}));
            C = cell(r,c);
            for v = 1:r
                C(v,:) = num2cell(app.OutD.(S{v}));
            end
            app.UITable.Data = C';
            app.OutM = [];
        end
    
        function data =  bin2hex(app, bin)
            if mod(length(bin), 8) ~= 0
                bin = [bin, zeors(1, 8 - mod(length(bin, 8)))];
            end
            f = length(bin) / 8;
            bin2 = bin(1:8);
            for v = 2:f
                a = (v-1)*8 + 1;
                bin2 = [bin2; bin(a:a+7)];
            end
            hex = binaryVectorToHex(bin2);
            data = [];
            for v = 1:f
                data = [data, hex{v}];
            end
        end
        
        function confirm_rem(app,src,event)
            display(event.SelectedOption);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            out = timerfind;
            delete(out);
            
            app.c_time = now;
            
            app.RecT = [];  % Queue of reccieved messages with time stamps
            app.RecD = struct;
            app.OutM = []; % message to payload
            app.OutD = struct; % data to encode in message
            app.TimeReceivedDropDown.ItemsData = 0;
            
            app.OutD.Alt = [];
            app.OutD.AltBuf = [];
            app.OutD.Lat = [];
            app.OutD.Long = [];
            app.OutD.Cut = [];
            app.OutD.Upd = [];
            app.OutD.HelA = [];
            app.OutD.HelB = [];
            app.OutD.HelG = [];
            app.OutD.BalA = [];
            app.OutD.BalB = [];
            app.OutD.BalG = [];
            app.OutD.Temp = [];
            app.OutD.Ctrl = [];
            app.OutD.ManAdj = [];
            app.OutD.ManSel = [];
            app.OutD.ManAmt = [];
            app.OutD.maxVel = [];
            
            app.RecD.Alt = [];
            app.RecD.Lat = [];
            app.RecD.Long = [];
            app.RecD.Vel = [];
            app.RecD.AccV = [];
            app.RecD.AccH = [];
            app.RecD.AccTheta = [];
            app.RecD.Temp = [];
            app.RecD.Prs = [];
            app.RecD.Bal = [];
            app.RecD.Hel = [];
            app.RecD.Ctrl = [];
            app.RecD.State = [];
            app.RecD.Emrg = [];
            app.RecD.Date = [];

            app.t = timer('ExecutionMode','fixedSpacing', ...
                'Name', 'CheckMailTimer', ...
                'TasksToExecute', Inf, ...
                'Period', 30);
            app.t.TimerFcn =  @(~, ~) app.checkMessages;
            start(app.t);
        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataTypeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Altitude'
                    plot(app.UIAxes, app.RecT, app.RecD.Alt);
                    app.UIAxes.Title.String = 'Altitude';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = 'm';
                case 'Latitude'
                    plot(app.UIAxes, app.RecT, app.RecD.Lat);
                    app.UIAxes.Title.String = 'Latitude';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = '';
                case 'Longitude'
                    plot(app.UIAxes, app.RecT, app.RecD.Long);
                    app.UIAxes.Title.String = 'Longitude';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = '';
                case 'Temperature'
                    plot(app.UIAxes, app.RecT, app.RecD.Temp);
                    app.UIAxes.Title.String = 'Temperature';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = 'C';
                case 'Pressure'
                    plot(app.UIAxes, app.RecT, app.RecD.Prs);
                    app.UIAxes.Title.String = 'Pressure';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = 'hPa';
                case 'Ballast Dropped'
                    plot(app.UIAxes, app.RecT, app.RecD.Bal);
                    app.UIAxes.Title.String = 'Ballast Used';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = '%';
                case 'Helium Vented'
                    plot(app.UIAxes, app.RecT, app.RecD.Bal);
                    app.UIAxes.Title.String = 'Helium Used';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = '%';
                case 'Vert. Acc'
                    plot(app.UIAxes, app.RecT, app.RecD.AccV);
                    app.UIAxes.Title.String = 'Vertical Acceleration';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = 'm/s^2';
                case 'Vert. Velocity'
                    plot(app.UIAxes, app.RecT, app.RecD.Vel);
                    app.UIAxes.Title.String = 'Vertical Velocity';
                    app.UIAxes.XLabel.String = 'Time Elapsed';
                    app.UIAxes.YLabel.String = 'm/s';
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            filename = ['Sent_Messages_', date, '.xlsx'];
            xlswrite(filename, app.UITable.Data);
        end

        % Button pushed function: ExportDataButton_2
        function ExportDataButton_2Pushed(app, event)
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            filename = ['Recieved_Data_', date, '.xlsx'];
            Data_Cell = struct2cell(app.RecD);
            Data_Cell = [Data_Cell; {app.RecT}];
            xlswrite(filename, Data_Cell);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: AcknowledgeButton
        function AcknowledgeButtonPushed(app, event)
            app.NoNewMessagesLamp.Color = 'black';
            app.NoNewMessagesLampLabel.Text = 'No New Messages';
        end

        % Value changed function: TimeReceivedDropDown
        function TimeReceivedDropDownValueChanged(app, event)
            value = app.TimeReceivedDropDown.Value;
            
            app.Altitude_rec.Value = app.RecD.Alt(value);
            app.Latitude_rec.Value = app.RecD.Lat(value);
            app.Longitude_rec.Value = app.RecD.Long(value);
            lat = app.RecD.Lat(value);
            long = app.RecD.Long(value);
            alt = app.RecD.Alt(value);
            app.Velocity.Value = app.RecD.Vel(value);
            app.Acceleration_V.Value = app.RecD.AccV(value);
            app.Acceleration_H.Value = app.RecD.AccH(value);
            app.Acceleration_Th.Value = app.RecD.AccTheta(value);
            app.Temperature_rec.Value = app.RecD.Temp(value);
            app.Pressure.Value = app.RecD.Prs(value);
            app.Ballast_rec.Value = app.RecD.Bal(value);
            app.Helium_rec.Value = app.RecD.Hel(value);
            if app.RecD.Ctrl(value) == 3
                app.ControlEditField.Value = 'Manual';
            elseif app.RecD.Ctrl(value) == 0
                app.ControlEditField.Value = 'Advanced';
            else
                app.ControlEditField.Value = 'Error';
            end
            
            if app.RecD.State(value) == 1
                app.StateEditField.Value = 'Pre-Launch';
            elseif app.RecD.State(value) == 2
                app.StateEditField.Value = 'Launch';
            elseif app.RecD.State(value) == 3
                app.StateEditField.Value = 'Falling';
            elseif app.RecD.State(value) == 4
                app.StateEditField.Value = 'Recovery';
            elseif app.RecD.State(value) == 5
                app.StateEditField.Value = 'Testing';
            else
                app.StateEditField.Value = 'Error';
            end
            
            if app.RecD.Emrg(value) == 3
                app.EmergencyLamp.Color = 'red';
            elseif app.RecD.Emrg(value) == 0
                app.EmergencyLamp.Color = 'green';
            else
                app.EmergencyLamp.Color = 'black';
            end
        end

        % Value changed function: SendMessageButton
        function SendMessageButtonValueChanged(app, event)
            % value = app.SendMessageButton.Value;
            app.OutD.Alt = [app.OutD.Alt, app.Altitude_Setpoint.Value];
            app.OutD.AltBuf = [app.OutD.AltBuf, app.Altitude_Range.Value];
            app.OutD.Lat = [app.OutD.Lat, app.Latitude.Value];
            app.OutD.Long = [app.OutD.Long, app.Longitude.Value];
            app.OutD.Cut = [app.OutD.Cut, 3*app.CutdownButton.Value];
            app.OutD.Upd = [app.OutD.Upd, app.Update_Rate.Value];
            app.OutD.HelA = [app.OutD.HelA, app.Helium_Alpha.Value];
            app.OutD.HelB = [app.OutD.HelB, app.Helium_Beta.Value];
            app.OutD.HelG = [app.OutD.HelG, app.Helium_Gamma.Value];
            app.OutD.BalA = [app.OutD.BalA, app.Ballast_Alpha.Value];
            app.OutD.BalB = [app.OutD.BalB, app.Ballast_Beta.Value];
            app.OutD.BalG = [app.OutD.BalG, app.Ballast_Gamma.Value];
            app.OutD.Temp = [app.OutD.Temp, app.Temp.Value];
            if app.ManualButton.Value == true
                app.OutD.Ctrl = [app.OutD.Ctrl, 3];
            elseif app.AdvancedButton.Value == true
                app.OutD.Ctrl = [app.OutD.Ctrl, 0];
            else
                app.OutD.Ctrl = [app.OutD.Ctrl, 1];
            end
            app.OutD.ManAdj = [app.OutD.ManAdj, app.ManualAdjustmentButton.Value];
            if app.Helium_Vent.Value ~= 0
                app.OutD.ManSel = [app.OutD.ManSel, 0];
                app.OutD.ManAmt = [app.OutD.ManAmt, app.Helium_Vent.Value];
            else
                app.OutD.ManSel = [app.OutD.ManSel, 1];
                app.OutD.ManAmt = [app.OutD.ManAmt, app.Ballast_Drop.Value];
            end
            app.OutD.maxVel = [app.OutD.maxVel, app.MaxVelocity.Value];
                
            app.OutM = encode_message(app);
            SendMessage(app);
            app.SendMessageButton.Value = false;
        end

        % Button pushed function: CheckMessagesButton
        function CheckMessagesButtonPushed(app, event)
            checkMessages(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SendMessageTab = uitab(app.TabGroup2);
            app.SendMessageTab.Title = 'Send Message';
            app.XComponentLabel = uilabel(app.SendMessageTab);
            app.XComponentLabel.Text = 'X Component';
            app.AltitudeSetpointLabel = uilabel(app.SendMessageTab);
            app.AltitudeSetpointLabel.Text = 'Altitude Setpoint';
            app.Altitude_Setpoint = uieditfield(app.SendMessageTab, 'numeric');
            app.Altitude_Setpoint.Limits = [0 120000];
            app.AltitudeRangeLabel = uilabel(app.SendMessageTab);
            app.AltitudeRangeLabel.Text = 'Altitude Range';
            app.Altitude_Range = uieditfield(app.SendMessageTab, 'numeric');
            app.Altitude_Range.Limits = [0 30000];
            app.AltitudeLimitsLabel = uilabel(app.SendMessageTab);
            app.AltitudeLimitsLabel.Text = 'Altitude Limits';
            app.TemperatureControlLabel = uilabel(app.SendMessageTab);
            app.TemperatureControlLabel.Text = 'Temperature Control';
            app.ValueLabel = uilabel(app.SendMessageTab);
            app.ValueLabel.Text = 'Value';
            app.Temp = uieditfield(app.SendMessageTab, 'numeric');
            app.Temp.Limits = [0 255];
            app.SetModeButtonGroup = uibuttongroup(app.SendMessageTab);
            app.SetModeButtonGroup.Title = 'Set Mode';
            app.ManualButton = uitogglebutton(app.SetModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AdvancedButton = uitogglebutton(app.SetModeButtonGroup);
            app.AdvancedButton.Text = 'Advanced';
            app.TabGroup3 = uitabgroup(app.SendMessageTab);
            app.ManualTab = uitab(app.TabGroup3);
            app.ManualTab.Title = 'Manual';
            app.AdjustmentValuesLabel = uilabel(app.ManualTab);
            app.AdjustmentValuesLabel.Text = 'Adjustment Values';
            app.HeliumVentsLabel = uilabel(app.ManualTab);
            app.HeliumVentsLabel.Text = 'Helium Vent (s)';
            app.Helium_Vent = uieditfield(app.ManualTab, 'numeric');
            app.BallastDropsLabel = uilabel(app.ManualTab);
            app.BallastDropsLabel.Text = 'Ballast Drops';
            app.Ballast_Drop = uieditfield(app.ManualTab, 'numeric');
            app.CutdownButton = uibutton(app.ManualTab, 'state');
            app.CutdownButton.Text = 'Cutdown';
            app.ManualAdjustmentButton = uibutton(app.ManualTab, 'state');
            app.ManualAdjustmentButton.Text = 'Manual Adjustment';
            app.AdvancedTab = uitab(app.TabGroup3);
            app.AdvancedTab.Title = 'Advanced';
            app.HeliumWeightsLabel = uilabel(app.AdvancedTab);
            app.HeliumWeightsLabel.Text = 'Helium Weights';
            app.AlphaLabel = uilabel(app.AdvancedTab);
            app.AlphaLabel.Text = 'Alpha';
            app.Helium_Alpha = uieditfield(app.AdvancedTab, 'numeric');
            app.Helium_Alpha.Limits = [0 10];
            app.BetaLabel = uilabel(app.AdvancedTab);
            app.BetaLabel.Text = 'Beta';
            app.Helium_Beta = uieditfield(app.AdvancedTab, 'numeric');
            app.GammaLabel = uilabel(app.AdvancedTab);
            app.GammaLabel.Text = 'Gamma';
            app.Helium_Gamma = uieditfield(app.AdvancedTab, 'numeric');
            app.BallastWeightsLabel = uilabel(app.AdvancedTab);
            app.BallastWeightsLabel.Text = 'Ballast Weights';
            app.BetaLabel_2 = uilabel(app.AdvancedTab);
            app.BetaLabel_2.Text = 'Beta';
            app.Ballast_Beta = uieditfield(app.AdvancedTab, 'numeric');
            app.GammaLabel_2 = uilabel(app.AdvancedTab);
            app.GammaLabel_2.Text = 'Gamma';
            app.Ballast_Gamma = uieditfield(app.AdvancedTab, 'numeric');
            app.AlphaLabel_2 = uilabel(app.AdvancedTab);
            app.AlphaLabel_2.Text = 'Alpha';
            app.Ballast_Alpha = uieditfield(app.AdvancedTab, 'numeric');
            app.MiscLabel = uilabel(app.AdvancedTab);
            app.MiscLabel.Text = 'Misc';
            app.MaxVelocityLabel = uilabel(app.AdvancedTab);
            app.MaxVelocityLabel.Text = 'Max. Velocity';
            app.MaxVelocity = uieditfield(app.AdvancedTab, 'numeric');
            app.MaxVelocity.Limits = [0 10];
            app.SendMessageButton = uibutton(app.SendMessageTab, 'state');
            app.SendMessageButton.ValueChangedFcn = createCallbackFcn(app, @SendMessageButtonValueChanged, true);
            app.SendMessageButton.Text = 'Send Message';
            app.LatitudeLabel_2 = uilabel(app.SendMessageTab);
            app.LatitudeLabel_2.Text = 'Latitude';
            app.Latitude = uieditfield(app.SendMessageTab, 'numeric');
            app.Latitude.Limits = [18 49];
            app.Latitude.Value = 18;
            app.PositionLabel_2 = uilabel(app.SendMessageTab);
            app.PositionLabel_2.Text = 'Position';
            app.LongitudeLabel_2 = uilabel(app.SendMessageTab);
            app.LongitudeLabel_2.Text = 'Longitude';
            app.Longitude = uieditfield(app.SendMessageTab, 'numeric');
            app.Longitude.Limits = [-100 -69];
            app.Longitude.Value = -100;
            app.SecondsLabel = uilabel(app.SendMessageTab);
            app.SecondsLabel.Text = 'Seconds';
            app.Update_Rate = uieditfield(app.SendMessageTab, 'numeric');
            app.Update_Rate.Limits = [0 2500];
            app.UpdateRateLabel = uilabel(app.SendMessageTab);
            app.UpdateRateLabel.Text = 'Update Rate';
            app.SentMessageListTab = uitab(app.TabGroup2);
            app.SentMessageListTab.Title = 'Sent Message List';
            app.UITable = uitable(app.SentMessageListTab);
            app.UITable.ColumnName = {'Altitude'; 'Alt. Buffer'; 'Longitude'; 'Latitude'; 'Cutdown'; 'Update Rate'; 'Hel. A'; 'Hel. B '; 'Hel. G'; 'Bal. A'; 'Bal. B'; 'Bal. G'; 'Temperature'; 'Mode'; 'Manual Adjust'; 'Manual Select'; 'Manual Amount'; 'Min. Velocity'};
            app.UITable.RowName = {};
            app.ExportDataButton = uibutton(app.SentMessageListTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ViewRecievedMessagesTab = uitab(app.TabGroup2);
            app.ViewRecievedMessagesTab.Title = 'View Recieved Messages';
            app.LatitudeLabel = uilabel(app.ViewRecievedMessagesTab);
            app.LatitudeLabel.Text = 'Latitude';
            app.Latitude_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Latitude_rec.ValueDisplayFormat = '%.10f';
            app.Latitude_rec.Editable = 'off';
            app.LongitudeLabel = uilabel(app.ViewRecievedMessagesTab);
            app.LongitudeLabel.Text = 'Longitude';
            app.Longitude_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Longitude_rec.ValueDisplayFormat = '%.10f';
            app.Longitude_rec.Editable = 'off';
            app.AltitudeLabel = uilabel(app.ViewRecievedMessagesTab);
            app.AltitudeLabel.Text = 'Altitude';
            app.Altitude_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Altitude_rec.Editable = 'off';
            app.PositionLabel = uilabel(app.ViewRecievedMessagesTab);
            app.PositionLabel.Text = 'Position';
            app.ToViewDataSelectTimeReceivedDropDownLabel = uilabel(app.ViewRecievedMessagesTab);
            app.ToViewDataSelectTimeReceivedDropDownLabel.Text = 'To View Data Select Time Received';
            app.TimeReceivedDropDown = uidropdown(app.ViewRecievedMessagesTab);
            app.TimeReceivedDropDown.Items = {'00-00-2018 00:00:00'};
            app.TimeReceivedDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeReceivedDropDownValueChanged, true);
            app.TimeReceivedDropDown.Value = '00-00-2018 00:00:00';
            app.MagnitudeLabel = uilabel(app.ViewRecievedMessagesTab);
            app.MagnitudeLabel.Text = 'Magnitude';
            app.Velocity = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Velocity.Editable = 'off';
            app.VelocityLabel = uilabel(app.ViewRecievedMessagesTab);
            app.VelocityLabel.Text = 'Velocity';
            app.AccelerationLabel = uilabel(app.ViewRecievedMessagesTab);
            app.AccelerationLabel.Text = 'Acceleration';
            app.VerticalMagLabel = uilabel(app.ViewRecievedMessagesTab);
            app.VerticalMagLabel.Text = 'Vertical Mag.';
            app.Acceleration_V = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Acceleration_V.Editable = 'off';
            app.HorizontalMagLabel = uilabel(app.ViewRecievedMessagesTab);
            app.HorizontalMagLabel.Text = 'Horizontal Mag.';
            app.Acceleration_H = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Acceleration_H.Editable = 'off';
            app.DirectionLabel = uilabel(app.ViewRecievedMessagesTab);
            app.DirectionLabel.Text = 'Direction';
            app.Acceleration_Th = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Acceleration_Th.Editable = 'off';
            app.TemperatureLabel = uilabel(app.ViewRecievedMessagesTab);
            app.TemperatureLabel.Text = 'Temperature';
            app.Temperature_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Temperature_rec.Editable = 'off';
            app.PressureLabel = uilabel(app.ViewRecievedMessagesTab);
            app.PressureLabel.Text = 'Pressure';
            app.Pressure = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Pressure.Editable = 'off';
            app.AmbientConditionsLabel = uilabel(app.ViewRecievedMessagesTab);
            app.AmbientConditionsLabel.Text = 'Ambient Conditions';
            app.PayloadStatusLabel = uilabel(app.ViewRecievedMessagesTab);
            app.PayloadStatusLabel.Text = 'Payload Status';
            app.HeliumVentedLabel = uilabel(app.ViewRecievedMessagesTab);
            app.HeliumVentedLabel.Text = 'Helium Vented';
            app.Helium_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Helium_rec.Editable = 'off';
            app.PayloadStatusLabel_2 = uilabel(app.ViewRecievedMessagesTab);
            app.PayloadStatusLabel_2.Text = 'Payload Status';
            app.Ballast_rec = uieditfield(app.ViewRecievedMessagesTab, 'numeric');
            app.Ballast_rec.Editable = 'off';
            app.BallastDroppedLabel = uilabel(app.ViewRecievedMessagesTab);
            app.BallastDroppedLabel.Text = 'Ballast Dropped';
            app.StateEditFieldLabel = uilabel(app.ViewRecievedMessagesTab);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.ViewRecievedMessagesTab, 'text');
            app.StateEditField.Value = 'Testing';
            app.EmergencyLampLabel = uilabel(app.ViewRecievedMessagesTab);
            app.EmergencyLampLabel.Text = 'Emergency';
            app.EmergencyLamp = uilamp(app.ViewRecievedMessagesTab);
            app.ControlEditFieldLabel = uilabel(app.ViewRecievedMessagesTab);
            app.ControlEditFieldLabel.Text = 'Control';
            app.ControlEditField = uieditfield(app.ViewRecievedMessagesTab, 'text');
            app.ControlEditField.Value = 'Advanced';
            app.RecievedDataGraphsTab = uitab(app.TabGroup2);
            app.RecievedDataGraphsTab.Title = 'Recieved Data Graphs';
            app.ExportDataButton_2 = uibutton(app.RecievedDataGraphsTab, 'push');
            app.ExportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_2Pushed, true);
            app.ExportDataButton_2.Text = 'Export Data';
            app.DataTypeButtonGroup = uibuttongroup(app.RecievedDataGraphsTab);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.AltitudeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.AltitudeButton.Text = 'Altitude';
            app.AltitudeButton.Value = true;
            app.LatitudeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.LatitudeButton.Text = 'Latitude';
            app.TemperatureButton = uiradiobutton(app.DataTypeButtonGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.PressureButton = uiradiobutton(app.DataTypeButtonGroup);
            app.PressureButton.Text = 'Pressure';
            app.BallastDroppedButton = uiradiobutton(app.DataTypeButtonGroup);
            app.BallastDroppedButton.Text = 'Ballast Dropped';
            app.VertAccButton = uiradiobutton(app.DataTypeButtonGroup);
            app.VertAccButton.Text = 'Vert. Acc';
            app.VertVelocityButton = uiradiobutton(app.DataTypeButtonGroup);
            app.VertVelocityButton.Text = 'Vert. Velocity';
            app.HeliumVentedButton = uiradiobutton(app.DataTypeButtonGroup);
            app.HeliumVentedButton.Text = 'Helium Vented';
            app.LongitudeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.LongitudeButton.Text = 'Longitude';
            app.UIAxes = uiaxes(app.RecievedDataGraphsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            app.AcknowledgeButton = uibutton(app.UIFigure, 'push');
            app.AcknowledgeButton.ButtonPushedFcn = createCallbackFcn(app, @AcknowledgeButtonPushed, true);
            app.AcknowledgeButton.Text = 'Acknowledge';
            app.NoNewMessagesLampLabel = uilabel(app.UIFigure);
            app.NoNewMessagesLampLabel.Text = 'No New Messages';
            app.NoNewMessagesLamp = uilamp(app.UIFigure);
            app.CheckMessagesButton = uibutton(app.UIFigure, 'push');
            app.CheckMessagesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckMessagesButtonPushed, true);
            app.CheckMessagesButton.Text = 'Check Messages';
        end
    end

    methods (Access = public)

        % Construct app
        function app = guiHighBall

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end